classdef CompFragSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        %Flags
        Done = false % trigger for calling script to intiate close request function once all procedures are done
        Up = false % trigger for rotation around Z axis (so that the notch is up)
        MsMov = false %trigger for activating UIFig ButtonMotion callback
        
        %Data
        v = [] % input vertices 

        Clsts = [] % The closest peak to mouse position
        PeakInd = [] % The indices of curvature peaks. Col1 = Neg, Col2 = Pos.
        PeaksCrds = [] % Current peaks coords in shown axes 
        OtLn = [] % 2D Outline of fragment
        SelPt = [] % The selceted point in the middle of the mandibular notch
        theta = [] %Rotation angle

        %Graphic public varibales
        ModPtch = [] % patch var for 3D model
        OtLnPl = [] % plot var for 2D Fourier outline
        PeakSc = [] % scatter var for curvature peaks
        Bstar = [] % scatter var for marking mouse pointer location
        
        %VarArgOut 
        RotV = [] % Stores the complete model's fully positioned vertex coordinates
        % In case both rami of a complete mandible is selected the
        % following variables are 2x1 cell arrays, otherwize the are nX3
        % matrices and a char vector
        RotvRam = [] % Stores the selected ramus' indices (for v) of fully positioned vertex coordinates
        RotfRam = [] % stroes the reindexed triangle list for selected ramus corresponding to v(RotvRam,:)
        FrgSd = [] % The fragment side (i.e. left or right ramus)
        ChinBasePt = [] %The coordinates of the lowest (Y) point of the triangles intersected by the symmetry plane
        NchMin = [] %The ZY coordinates of the lowermost position of the mandibular notch. The detection of X will be done only after curvature calculation.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, f, v)
            app.v = v;

            %Show model patch in Axes
            hold(app.UIAxes, 'on');
            app.ModPtch = patch(app.UIAxes, 'Faces', f, 'Vertices', v,...
                'FaceColor',[1,1,1], 'LineStyle','none','AmbientStrength',0.1,...
                'SpecularExponent',30,'SpecularStrength',0.1, 'FaceLighting', 'gouraud');
            L1 = light(app.UIAxes,'Position',[0,40,-60]);
            L2 = light(app.UIAxes,'Position',[0,-40,-60]);
            L3 = light(app.UIAxes,'Position',[0,40,60]);
            L4 = light(app.UIAxes,'Position',[0,-40,60]);
            axis(app.UIAxes, 'equal');
            
            %Ask user what is it
            InTyp = questdlg('Is the input model a complete mandible or ramus fragment?',...
                'Input Type Selector','Complete','Fragment','Complete');
            
            switch InTyp
                case 'Complete'
                    %Call poositioning on table procedure
                    RotVFull = PosOnTable(v,f);
                    app.ModPtch.Vertices = RotVFull;
                    app.FrgSd = questdlg('Which sides can be used?',...
                        'Fragment side selector','Both','Left','Right','Both');
                    Zcord =  [RotVFull(f(:,1),3),RotVFull(f(:,2),3),RotVFull(f(:,3),3)];
                    tri = find(~all(sign(Zcord)<0,2) & ~all(sign(Zcord)>0,2));
                    Ycord = [RotVFull(f(tri,1),2),RotVFull(f(tri,2),2),RotVFull(f(tri,3),2)];
                    [r,c] = find(Ycord==min(Ycord,[],'all'));
                    app.ChinBasePt = f(tri(r(1)),c(1));
                    app.RotV = ([0,0,1;0,1,0;-1,0,0]*RotVFull')';
                    [app.RotvRam, app.RotfRam, app.NchMin] = SegRam(app.RotV,f,app.FrgSd);
                    if strcmp(app.FrgSd,'Both')
                        app.FrgSd = {'Right';'Left'};
                    end
                    app.Done = true;

                case 'Fragment'
                    app.RotfRam = f;
                    app.ChinBasePt = [];
                    %Is it a left or right ramus
                    app.FrgSd = questdlg('Is the input model a left or right fragment?',...
                        'Fragment side selector','Left','Right','Left');
                    app.MsMov = true;
                    % get orthoprojection boundary cordinates, strong
                    % smoothing Fourier coeffs, curvature with peaks
                    Bcoords = boundary([v(:,1),v(:,2)]);
                    Bcoords = v(Bcoords,1:2);
                    [TotLen, CoefsX, CoefsY, ~, ~] = FourierCoefs(Bcoords, false);
                    NewDist = (0:TotLen/200:TotLen)';
                    Wn = 1./(1+exp(1+(((1:1:size(CoefsX,1))-6)/2)))'; %Strong smoothing for initial curvature peaks detection
                    K = CurvCalc(NewDist,Wn,CoefsX,CoefsY);
                    [PIndPos,PIndNeg] = FindPeaks(K, NewDist,true);


                    %Reduce smoothing, fine tune curvature peaks, and plot
                    %outline on 3D model patch already in UIAxes
                    Wn = ones(size(CoefsX,1),1);
                    K = CurvCalc(NewDist,Wn,CoefsX,CoefsY);
                    PIndPos = PercPeaks(PIndPos,K,NewDist);
                    PIndNeg = PercPeaks(PIndNeg,K,NewDist);
                    app.PeakInd = {PIndNeg(:,1),PIndPos(:,1)};
                    Xcoord = zeros(size(NewDist));
                    Ycoord = zeros(size(NewDist));
                    for i = 1:size(NewDist,1)
                        Xcoord(i) = Sum4Fourier(TotLen,CoefsX,NewDist(i),Wn);
                        Ycoord(i) = Sum4Fourier(TotLen,CoefsY,NewDist(i),Wn);
                    end
                    app.OtLn = [Xcoord, Ycoord];
                    app.PeaksCrds = [Xcoord(PIndNeg(:,1)),Ycoord(PIndNeg(:,1))];
                    app.OtLnPl = plot(app.UIAxes,Xcoord,Ycoord,'k','LineWidth',2);
                    app.PeakSc = scatter(app.UIAxes,Xcoord(PIndNeg(:,1)),...
                        Ycoord(PIndNeg(:,1)),'ob','filled');

                    app.UIAxes.Title.String = 'Select the middle of the mandibular notch';
                    
                    % Mouse position and user selection are NaNs at startup
                    app.Bstar = scatter(app.UIAxes,NaN,NaN,185,'pr','filled'); 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
         
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %Get mouse coords
            FgCrd = app.UIFigure.CurrentPoint;
            if app.MsMov
                %Coordinates are for the whole Figure, check if it's in Axes
                InAxFlg = FgCrd(1) >= app.UIAxes.Position(1) && FgCrd(1) <= sum(app.UIAxes.Position([1,3]))...
                    && FgCrd(2) >= app.UIAxes.Position(2) && FgCrd(2) <= sum(app.UIAxes.Position([2,4]));
                if InAxFlg
                    %Find the closest peak to mouse location and update Bstar
                    MsCrd = app.UIAxes.CurrentPoint(1,1:2);
                    Dist = vecnorm(app.PeaksCrds-MsCrd,2,2);
                    app.Clsts = find(Dist==min(Dist));
                    app.Bstar.XData = app.PeaksCrds(app.Clsts,1);
                    app.Bstar.YData = app.PeaksCrds(app.Clsts,2);
                else
                    app.Clsts= [];
                    app.Bstar.XData = NaN;
                    app.Bstar.YData = NaN;
                end
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            ClstsN = app.Clsts;
            if ~isempty(ClstsN)
                %First rotation
                if ~app.Up
                    app.SelPt = [app.PeaksCrds(app.Clsts,1),app.PeaksCrds(app.Clsts,2)];
                    % Line between fragment centroid and notch center is
                    % made parallel to Y pointing towards positive
                    app.theta = acos(dot(app.SelPt./vecnorm(app.SelPt,2,2),[0,1],2));
                    if app.SelPt(1) < 0
                        app.theta = -app.theta;
                    end
                    RMat = [cos(app.theta),-sin(app.theta),0;...
                        sin(app.theta),cos(app.theta),0; 0,0,1];
                    RotvUp = (RMat*app.v')';
                    OtLnUp = (RMat*[app.OtLn,zeros(size(app.OtLn,1),1)]')';
                    app.SelPt = (RMat*[app.SelPt,0]')';
                    app.SelPt = app.SelPt(1:2);
                    %Update peak coordinates to positive ones
                    app.PeaksCrds = [OtLnUp(app.PeakInd{2},1),OtLnUp(app.PeakInd{2},2)];
                    app.ModPtch.Vertices = RotvUp;
                    app.OtLnPl.XData = OtLnUp(:,1);
                    app.OtLnPl.YData = OtLnUp(:,2);
                    app.PeakSc.XData = OtLnUp(app.PeakInd{2},1);
                    app.PeakSc.YData = OtLnUp(app.PeakInd{2},2);
                    axis(app.UIAxes, 'equal');
                    app.UIAxes.Title.String = 'Select the condyle';
                    app.Up = true;
                else
                    app.SelPt = [app.SelPt; [app.PeaksCrds(app.Clsts,1),app.PeaksCrds(app.Clsts,2)]];
                    if app.SelPt(2,1) - app.SelPt(1,1) < 0
                        % meaning that when a left ramus is projected onto symmetry plane
                        % as XY the condyle is left to the middle of the mandibular notch
                        % and when a right rmus the condyle is to the right
                        % - In such a case, model is flipped
                        
                        RotvBack = app.ModPtch.Vertices;
                        RMat = [cos(pi),0,sin(pi);0,1,0;-sin(pi),0,cos(pi)];
                        RotvBack = (RMat*RotvBack')';
                        OtLnBack = [app.OtLnPl.XData', app.OtLnPl.YData'];
                        OtLnBack = (RMat*[OtLnBack, zeros(size(OtLnBack,1),1)]')';
                        app.ModPtch.Vertices = RotvBack;
                        app.OtLnPl.XData = OtLnBack(:,1);
                        app.OtLnPl.XData = OtLnBack(:,1);
                    else
                        RotvBack = app.ModPtch.Vertices;
                    end
                    PosOK = questdlg('Is the positioning OK?',...
                        'Confirm','OK','Restart','OK');
                    %User selcts if positioning is OK - the notch is
                    %positioned at the upper Y axis and the surface
                    %observed is the external one.
                    if strcmp(PosOK,'Restart')
                        app.ModPtch.Vertices = app.v;
                        app.OtLnPl.XData = app.OtLn(:,1);
                        app.OtLnPl.YData = app.OtLn(:,2);
                        app.PeaksCrds = [app.OtLn(app.PeakInd{1},1),app.OtLn(app.PeakInd{1},2)];
                        app.PeakSc.XData = app.OtLn(app.PeakInd{1},1);
                        app.PeakSc.YData = app.OtLn(app.PeakInd{1},2);
                        app.SelPt = [];
                        app.Up = false;
                    else
                        if strcmp(app.FrgSd,'Left')
                            app.theta = -0.5*pi;
                        else
                            app.theta = 0.5*pi;
                        end
                        RMat = [cos(app.theta),0,sin(app.theta);0,1,0;-sin(app.theta),0,cos(app.theta)];
                        app.RotV = (RMat*RotvBack')';
                        app.RotvRam = (1:size(app.RotV,1))';
                        app.NchMin = (RMat*[app.SelPt(1,:),0]')';
                        app.Done = true;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompFragSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end